classdef session2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image2                   matlab.ui.control.Image
        Tone2FrequencyKnob       matlab.ui.control.Knob
        Tone2FrequencyKnobLabel  matlab.ui.control.Label
        Tone1FrequencyKnob       matlab.ui.control.Knob
        Tone1FrequencyKnobLabel  matlab.ui.control.Label
        VitisDSPLibraryCourseSession2Label  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        UIAxes6                  matlab.ui.control.UIAxes
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs1_Msps % Description
        fp_MHz
        fs_MHz
        A1
        A2
        A
        Ftone1_MHz
        Ftone2_MHz
        taps1_fxp
        taps1_flt
        sig_i_fxp
        sig_i_flt
        sig_o_fxp
        sig_o_flt
        fft_i_fxp
        fft_i_flt
        fft_o_fxp
        fft_o_flt
        Nfft
    end
    
    methods (Access = private)
        
        function app = config(app)
            app.Fs1_Msps = 400;
            app.fp_MHz = 100;
            app.fs_MHz = 150;
            app.A1 = 0.2;
            app.A2 = 0.4;
            app.A = app.A1 + app.A2;
            app.Ftone1_MHz = app.Tone1FrequencyKnob.Value;
            app.Ftone2_MHz = app.Tone2FrequencyKnob.Value;
            app.Tone1FrequencyKnob.Limits=[0,0.5*app.Fs1_Msps];
            app.Tone2FrequencyKnob.Limits=[0,0.5*app.Fs1_Msps];
            app.Nfft = 256;
            % Design a filter with 20*log10(1e-3) = -60 dB stop band attenuation:
            taps1_orig = firgr('mineven',[0,app.fp_MHz,app.fs_MHz,0.5*app.Fs1_Msps]/(0.5*app.Fs1_Msps),[1,1,0,0],[1e-3,1e-3]);
            % Quantize to 'int16':
            app.taps1_fxp = fi(taps1_orig,1,16,15,'RoundingMethod','Nearest','OverflowAction','Saturate');
            app.taps1_flt = double(app.taps1_fxp);
            app.UIAxes.XLabel.String = 'Samples';
            app.UIAxes.YLabel.String = 'Magnitude';
            app.UIAxes.Title.String = sprintf('Filter #1 Impulse Response (%g taps)',length(taps1_orig));
            stem(app.UIAxes,app.taps1_flt);
        end
        
        function app = generate_tones(app)
            % Note: Total number of samples will be 4 x WIN_SIZE, so we are generating data for 4 AIE graph cycles:
            WIN_SIZE = 2048;
            Nsamp1 = 4 * WIN_SIZE;
            tone1 = app.A1 * exp(1i*2*pi*app.Ftone1_MHz/app.Fs1_Msps*[0:Nsamp1-1]);
            tone2 = app.A2 * exp(1i*2*pi*app.Ftone2_MHz/app.Fs1_Msps*[0:Nsamp1-1]);
            sig_i = tone1 + tone2;

            % Quantize:
            app.sig_i_fxp  = fi(sig_i,1,16,15,'RoundingMethod','Nearest','OverflowAction','Saturate');
            app.sig_i_flt = double(app.sig_i_fxp);

            % Apply filter to generate expected outputs:
            sig_o = filter(app.taps1_flt,1,app.sig_i_flt);

            % Quantize:
            app.sig_o_fxp = fi(sig_o,1,16,15,'RoundingMethod','Nearest','OverflowAction','Saturate');
            app.sig_o_flt = double(app.sig_o_fxp);

            scale = 0.5^6;
            % Compute FFT of two-tone input:
            tmp = reshape(app.sig_i_flt,app.Nfft,[]);
            fft_i = scale*fft(tmp,[],1);
            app.fft_i_fxp = fi(fft_i,1,16,15,'RoundingMethod','Nearest','OverflowAction','Saturate');
            app.fft_i_flt = double(app.fft_i_fxp);

            % Compute FFT of filtered output:
            tmp = reshape(app.sig_o_flt,app.Nfft,[]);
            fft_o = scale*fft(tmp,[],1);
            app.fft_o_fxp = fi(fft_o,1,16,15,'RoundingMethod','Nearest','OverflowAction','Saturate');
            app.fft_o_flt = double(app.fft_o_fxp);
        end
        
        function app = update_plots(app)
            [H1,f1] = freqz(app.taps1_flt,1,8192,app.Fs1_Msps);
            plot(app.UIAxes2,f1,20*log10(abs(H1)),'b-'); 
            app.UIAxes2.XLabel.String = 'Frequency (MHz)';
            app.UIAxes2.YLabel.String = 'Magnitude';
            app.UIAxes2.Title.String = 'FIR Frequency Response';
            xline(app.UIAxes2,app.Ftone1_MHz,'r');
            xline(app.UIAxes2,app.Ftone2_MHz,'r');
            v = axis(app.UIAxes2); axis(app.UIAxes2,[v(1),v(2),-80,v(4)]);

            plot(app.UIAxes3,real(app.sig_i_flt),'b.-');
            app.UIAxes3.XLabel.String = 'Sample Index'; 
            app.UIAxes3.YLabel.String = 'Real part'; 
            app.UIAxes3.Title.String = 'FIR Time-Domain Input (A)';
            axis(app.UIAxes3,[1,128,-app.A,+app.A]);
            
            plot(app.UIAxes4,real(app.sig_o_flt),'b.-');
            app.UIAxes4.XLabel.String = 'Sample Index'; 
            app.UIAxes4.YLabel.String = 'Real part'; 
            app.UIAxes4.Title.String = 'FIR Time-Domain Output (B)';
            axis(app.UIAxes4,[1,128,-app.A,+app.A]);

            ff = linspace(0,app.Fs1_Msps,1+app.Nfft); ff = ff(1:end-1);
            plot(app.UIAxes5,ff,abs(app.fft_i_flt(:,2)),'b.-');
            app.UIAxes5.XLabel.String = 'Frequency (MHz)';
            app.UIAxes5.YLabel.String = 'Magnitude';
            app.UIAxes5.Title.String = 'FIR Frequency-Domain Input (A)';

            plot(app.UIAxes6,ff,abs(app.fft_o_flt(:,2)),'b.-');
            app.UIAxes6.XLabel.String = 'Frequency (MHz)';
            app.UIAxes6.YLabel.String = 'Magnitude';
            app.UIAxes6.Title.String = 'FFT Output (C)';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Tone1FrequencyKnob
        function Tone1FrequencyKnobValueChanged(app, event)
            config(app);
            generate_tones(app);
            update_plots(app);
        end

        % Value changed function: Tone2FrequencyKnob
        function Tone2FrequencyKnobValueChanged(app, event)
            config(app);
            generate_tones(app);
            update_plots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'amd_logo.jpg';
            app.VitisDSPLibraryCourseSession2Label = uilabel(app.UIFigure);
            app.VitisDSPLibraryCourseSession2Label.Text = 'Vitis DSP Library Course: Session #2';
            app.Tone1FrequencyKnobLabel = uilabel(app.UIFigure);
            app.Tone1FrequencyKnobLabel.Text = 'Tone #1 Frequency';
            app.Tone1FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.Tone1FrequencyKnob.Limits = [0 200];
            app.Tone1FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @Tone1FrequencyKnobValueChanged, true);
            app.Tone1FrequencyKnob.Value = 20;
            app.Tone2FrequencyKnobLabel = uilabel(app.UIFigure);
            app.Tone2FrequencyKnobLabel.Text = 'Tone #2 Frequency';
            app.Tone2FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.Tone2FrequencyKnob.Limits = [0 200];
            app.Tone2FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @Tone2FrequencyKnobValueChanged, true);
            app.Tone2FrequencyKnob.Value = 150;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'system-diag.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = session2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end